classdef TensegrityAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveStructureMenu            matlab.ui.container.Menu
        LoadStructureMenu            matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        EditStructurePanel           matlab.ui.container.Panel
        RemoveMemberButton           matlab.ui.control.Button
        MembertoEditDropDown         matlab.ui.control.DropDown
        MembertoEditDropDownLabel    matlab.ui.control.Label
        NodetoEditDropDown           matlab.ui.control.DropDown
        NodetoEditDropDownLabel      matlab.ui.control.Label
        RemoveNodeButton             matlab.ui.control.Button
        GraphScale1mEditField        matlab.ui.control.NumericEditField
        GraphScale1mEditFieldLabel   matlab.ui.control.Label
        MemberInputPanel             matlab.ui.container.Panel
        MemberTypeDropDown           matlab.ui.control.DropDown
        MemberTypeLabel              matlab.ui.control.Label
        DensityEditField             matlab.ui.control.NumericEditField
        DensityEditFieldLabel        matlab.ui.control.Label
        CrosssectionalAreaEditField  matlab.ui.control.NumericEditField
        CrosssectionalAreaEditFieldLabel  matlab.ui.control.Label
        YoungsModulusEditField       matlab.ui.control.NumericEditField
        YoungsModulusEditFieldLabel  matlab.ui.control.Label
        RemoveLastButton_2           matlab.ui.control.Button
        SubmitButton_2               matlab.ui.control.Button
        EndDropDown                  matlab.ui.control.DropDown
        EndDropDownLabel             matlab.ui.control.Label
        StartDropDown                matlab.ui.control.DropDown
        StartNodeLabel               matlab.ui.control.Label
        NodeInputPanel               matlab.ui.container.Panel
        RemoveLastButton             matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        zEditField                   matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        InputForceButton             matlab.ui.control.Button
        SaveFrequenciesButton        matlab.ui.control.Button
        FindFrequenciesButton        matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ContextMenu                  matlab.ui.container.ContextMenu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Structure = StructureLoader;
        Frequencies
        Mode
        InputForceOption = 'Z-axis';
    end
    
    methods (Access = private)

            
        function Update_Plot(app)
            % UPDATE_PLOT Updates the plot of the structure in the app's UIAxes.
        
            % Store relevant attributes
            nodeCoordinates = app.Structure.nodeCoordinates;
            numNodes = app.Structure.numNodes;
            startNodeCoordinates = app.Structure.startNodeCoordinates;
            endNodeCoordinates = app.Structure.endNodeCoordinates;
            numMembers = app.Structure.numMembers;
            memberType = app.Structure.memberType;
        
            % Clear the UIAxes before plotting
            cla(app.UIAxes)
        
            % If there are no nodes, return immediately
            if numNodes == 0
                return
            end
        
            % Hold allows everything to be plotted on the same graph
            hold(app.UIAxes, "on");
        
            % Plot the coordinates of the nodes as red o's
            for i = 1:numNodes
                plot3(app.UIAxes, nodeCoordinates(i,1), nodeCoordinates(i,2), nodeCoordinates(i,3), 'o','Color','r')
                nodeLabel = num2str(i);
                text(app.UIAxes, nodeCoordinates(i,1), nodeCoordinates(i,2), nodeCoordinates(i,3), nodeLabel)
            end
        
            % Plot the members with struts blue, bars black, and cables as
            % dashed purple lines
            for i = 1:numMembers
                pts = [startNodeCoordinates(i,:); endNodeCoordinates(i,:)];
                midpoint = (startNodeCoordinates(i,:) + endNodeCoordinates(i,:))/2;
                memberLabel = num2roman(i);
        
                switch char(memberType(i))
                    case 's'
                        plot3(app.UIAxes, pts(:,1), pts(:,2), pts(:,3),'Color','b')
                    case 'c'
                        plot3(app.UIAxes, pts(:,1), pts(:,2), pts(:,3),"--", 'Color','m')
                    case 'b'
                        plot3(app.UIAxes, pts(:,1), pts(:,2), pts(:,3),'Color','k')
                    otherwise
                        plot3(app.UIAxes, pts(:,1), pts(:,2), pts(:,3),'Color','k')
                end
        
                % Add member label
                text(app.UIAxes, midpoint(1), midpoint(2), midpoint(3), memberLabel, 'Color', 'r')
            end 
        
            hold(app.UIAxes, "off");
            app.UIAxes.DataAspectRatio = [1 1 1];
            grid(app.UIAxes, "on");
        end
        
        function app = Update_Lists(app)
            numNodes = app.Structure.numNodes;
            numMembers = app.Structure.numMembers;
        
            nodeList = string(1:numNodes);
            memberList = string(1:numMembers);
            
            % Convert memberList to Roman numerals
            for i = 1:numMembers
                memberList(i) = num2roman(str2double(memberList(i)));
            end
        
            app.StartDropDown.Items = nodeList;
            app.EndDropDown.Items = nodeList;
            app.NodetoEditDropDown.Items = nodeList;
            app.MembertoEditDropDown.Items = memberList; % Updated to display Roman numerals
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % SUBMITBUTTONPUSHED Callback function for the button that adds a new node.
    
            % Get the node coordinates from the app's edit fields
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
        
            % Determine if the node is fixed or unfixed
            if strcmp(app.Switch.Value, 'Unfixed')
                f = 0;
            else 
                f = 1;
            end
        
            % Add the new node to the structure
            app.Structure = app.Structure.addNode([x y z], f);
        
            % Update the node and member lists and the plot
            Update_Lists(app);
            Update_Plot(app);
        end

        % Button pushed function: SubmitButton_2
        function SubmitButton_2Pushed(app, event)
            % SUBMITBUTTON_2PUSHED Callback function for the button that adds a new member.
            if app.Structure.numNodes == 0
                return
            end

            % Get the start and end node indices from the app's dropdown menus
            startIndex = str2double(app.StartDropDown.Value);
            endIndex = str2double(app.EndDropDown.Value);
        
            % Ensure that the start and end node is different
            if startIndex == endIndex
                return
            end
        
            % Determine the member type from the app's dropdown menu
            value = app.MemberTypeDropDown.Value;
        
            if value(1) == 'S'
                memType = 's';
            elseif value(1) == 'C'
                memType = 'c';
            elseif value(1) == 'B'
                memType = 'b';
            end
        
            % Collect the material properties from the app's edit fields
            material.memberType = memType;
            material.youngsModulus = app.YoungsModulusEditField.Value;
            material.density = app.DensityEditField.Value;
            material.crossSectionalArea = app.CrosssectionalAreaEditField.Value;
            material.mass_per_unit_length = material.density*material.crossSectionalArea;
        
            % Add the new member to the structure
            app.Structure = app.Structure.addMember(startIndex, endIndex, material);
        
            % Update the node and member lists and the plot
            Update_Lists(app);
            Update_Plot(app);
        end

        % Value changed function: GraphScale1mEditField
        function GraphScale1mEditFieldValueChanged(app, event)
            value = app.GraphScale1mEditField.Value;
            app.Structure.scale = value;
        end

        % Button pushed function: RemoveLastButton
        function RemoveLastButtonPushed(app, event)
            app.Structure = app.Structure.removeNode(app.Structure.numNodes);

            Update_Lists(app);
            Update_Plot(app);
        end

        % Button pushed function: RemoveLastButton_2
        function RemoveLastButton_2Pushed(app, event)
            app.Structure = app.Structure.removeMember(app.Structure.numMembers);

            Update_Lists(app);
            Update_Plot(app);
        end

        % Button pushed function: RemoveNodeButton
        function RemoveNodeButtonPushed(app, event)
            value = str2double(app.NodetoEditDropDown.Value);
            app.Structure = app.Structure.removeNode(value);

            Update_Lists(app);
            Update_Plot(app);
        end

        % Button pushed function: RemoveMemberButton
        function RemoveMemberButtonPushed(app, event)
            value = roman2num(app.MembertoEditDropDown.Value);
            app.Structure = app.Structure.removeMember(value);

            Update_Lists(app);
            Update_Plot(app);
        end

        % Button pushed function: FindFrequenciesButton
        function FindFrequenciesButtonPushed(app, event)
            % Get the structure from the app
            structure = app.Structure;
            
            % Extract the values from the structure
            node_positions = structure.nodeCoordinates*structure.scale;
            connectivity = [structure.startIndex, structure.endIndex];
            mass_per_unit_length = structure.mass_per_unit_length;
            YoungsModulus = structure.youngsModulus;
            CrossSectionalArea = structure.crossSectionalArea;
            

            % Find the fixed nodes and tension members
            fixed_nodes = find(structure.fixedValue == 1);
            tension_members = find(strcmp(structure.memberType, 'c'));
        
            % Calculate natural frequencies
            g = zeros(3 * size(node_positions, 1), 1);

            % Set the gravitational acceleration to -9.81 m/s² for each node in the z-direction (assuming the Z-axis as vertical)
            if strcmp(app.InputForceOption, 'X-axis')
                g(1:3:end) = -9.81;
            elseif strcmp(app.InputForceOption, 'Y-axis')
                g(2:3:end) = -9.81;
            else
                g(3:3:end) = -9.81;
            end
            
        
            tensegrity_model = TensegrityModelling(node_positions, connectivity, mass_per_unit_length, g, tension_members);
        
            % Fix the specified nodes
            fixed_nodes_indices = tensegrity_model.fix_nodes(fixed_nodes);
        
            % Compute the equilibrium matrix
            equilibrium_matrix = tensegrity_model.computeEquilibriumMatrix();
        
            % Compute the global mass matrix and gravitational forces
            [M_global, gravitational_forces] = tensegrity_model.computeGlobalMassMatrixAndGravitationalForces();
        
            % Compute the force densities
            force_densities = tensegrity_model.computeForceDensities(fixed_nodes);
        
            % Create a StiffnessMatrix object
            stiffness_matrix = StiffnessMatrix(node_positions, connectivity, force_densities, YoungsModulus, CrossSectionalArea);
        
            % Compute the linear stiffness matrix
            linear_stiffness_matrix = stiffness_matrix.computeLinearStiffnessMatrix();
        
            % Compute the geometric stiffness matrix
            geometric_stiffness_matrix = stiffness_matrix.computeGeometricStiffnessMatrix();
        
            % Calculate the tangent stiffness matrix
            tangent_stiffness_matrix = linear_stiffness_matrix + geometric_stiffness_matrix;
        
            % Generate the R matrix
            num_nodes = height(node_positions);
            all_nodes = 1:num_nodes; % A list of all nodes
            free_nodes = setdiff(all_nodes, fixed_nodes); % Find the free nodes by excluding the fixed nodes
            R = generateRigidBodyModes(fixed_nodes, free_nodes, num_nodes);
        
            % Compute reduced matrices
            M_reduced = R' * M_global * R;
            K_reduced = R' * tangent_stiffness_matrix * R;
        
            % Create a NaturalFrequencies object
            natural_frequencies_obj = NaturalFrequencies(K_reduced, M_reduced);
            
            % Compute the natural frequencies
            natural_frequencies = natural_frequencies_obj.computeNaturalFrequencies();
            
            % Display the natural frequencies in the table
            modeNumbers = (1:length(natural_frequencies))';
            app.Mode = modeNumbers;
            app.Frequencies = natural_frequencies;
            frequencyTable = table(modeNumbers, natural_frequencies);
            app.UITable.Data = frequencyTable;

            function [R] = generateRigidBodyModes(~, free_nodes, num_nodes)
                R = zeros(3 * num_nodes, length(free_nodes) * 6);
                col_idx = 1;
            
                for node_idx = free_nodes
                    idx = (node_idx - 1) * 3 + 1;
            
                    % Translations (x, y, and z)
                    R(idx, col_idx) = 1;
                    R(idx + 1, col_idx + 1) = 1;
                    R(idx + 2, col_idx + 2) = 1;
            
                    % Rotations (around x, y, and z)
                    R(idx + 1, col_idx + 3) = 1;
                    R(idx + 2, col_idx + 4) = -1;
                    R(idx, col_idx + 5) = 1;
                    R(idx + 2, col_idx + 6) = 1;
                    R(idx, col_idx + 7) = -1;
                    R(idx + 1, col_idx + 8) = 1;
            
                    col_idx = col_idx + 6;
                end
            end
        end

        % Button pushed function: SaveFrequenciesButton
        function SaveFrequenciesButtonPushed(app, event)
            Modes = app.Mode;
            Frequencies = app.Frequencies;

            T = table(Modes, Frequencies); %#ok<*ADPROPLC>
            
            writetable(T, "CalculatedFrequencies.xlsx")
        end

        % Menu selected function: SaveStructureMenu
        function SaveStructureMenuSelected(app, event)
            app.Structure = app.Structure.Save_Structure();
        end

        % Menu selected function: LoadStructureMenu
        function LoadStructureMenuSelected(app, event)
            app.Structure = StructureLoader;
            app.Structure = app.Structure.Load_Structure();

            Update_Lists(app);
            Update_Plot(app);
        end

        % Button pushed function: InputForceButton
        function InputForceButtonPushed(app, event)
            % Create a new modal figure for the input force options
            % Initially gravity is assumed to act on the Z-axis
            fig = uifigure('Name', 'Axis of Gravity', 'Position', [100 100 400 300], 'Visible', 'off', 'WindowState', 'minimized');
            fig.Visible = 'on';
            
            % Create a button group for radio buttons
            bg = uibuttongroup(fig, 'Position', [50 100 300 150]);
            
            % Create radio buttons for each axis
            r1 = uiradiobutton(bg, 'Text', 'X-axis', 'Position', [10 115 150 20]);
            r2 = uiradiobutton(bg, 'Text', 'Y-axis', 'Position', [10 85 150 20]);
            r3 = uiradiobutton(bg, 'Text', 'Z-axis', 'Position', [10 55 150 20]);
            
            % Set the default value for the button group
            bg.SelectedObject = r1;
            
            % Create the 'OK' button to save the user's choice
            okButton = uibutton(fig, 'Text', 'OK', 'Position', [150 50 100 30], 'ButtonPushedFcn', @(src, event) closeFig(app, src, event, fig, bg));
        end
        
        function closeFig(app, src, event, fig, bg)
            % Save user choice for later reference in app property 'InputForceOption'
            app.InputForceOption = bg.SelectedObject.Text;
            
            % Close the figure
            close(fig);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {535, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveStructureMenu = uimenu(app.FileMenu);
            app.SaveStructureMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveStructureMenuSelected, true);
            app.SaveStructureMenu.Text = 'Save Structure';
            app.LoadStructureMenu = uimenu(app.FileMenu);
            app.LoadStructureMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadStructureMenuSelected, true);
            app.LoadStructureMenu.Text = 'Load Structure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Structure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NodeInputPanel = uipanel(app.LeftPanel);
            app.NodeInputPanel.Title = 'Node Input';
            app.xEditFieldLabel = uilabel(app.NodeInputPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.NodeInputPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.NodeInputPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NodeInputPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.NodeInputPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.NodeInputPanel, 'numeric');
            app.Switch = uiswitch(app.NodeInputPanel, 'slider');
            app.Switch.Items = {'Fixed', 'Unfixed'};
            app.Switch.Value = 'Unfixed';
            app.SubmitButton = uibutton(app.NodeInputPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.RemoveLastButton = uibutton(app.NodeInputPanel, 'push');
            app.RemoveLastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastButtonPushed, true);
            app.RemoveLastButton.Text = 'Remove Last';
            app.MemberInputPanel = uipanel(app.LeftPanel);
            app.MemberInputPanel.Title = 'Member Input';
            app.StartNodeLabel = uilabel(app.MemberInputPanel);
            app.StartNodeLabel.Text = 'Start ';
            app.StartDropDown = uidropdown(app.MemberInputPanel);
            app.StartDropDown.Items = {};
            app.StartDropDown.Value = {};
            app.EndDropDownLabel = uilabel(app.MemberInputPanel);
            app.EndDropDownLabel.Text = 'End';
            app.EndDropDown = uidropdown(app.MemberInputPanel);
            app.EndDropDown.Items = {''};
            app.EndDropDown.Value = '';
            app.SubmitButton_2 = uibutton(app.MemberInputPanel, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_2Pushed, true);
            app.SubmitButton_2.Text = 'Submit';
            app.RemoveLastButton_2 = uibutton(app.MemberInputPanel, 'push');
            app.RemoveLastButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastButton_2Pushed, true);
            app.RemoveLastButton_2.Text = 'Remove Last';
            app.YoungsModulusEditFieldLabel = uilabel(app.MemberInputPanel);
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus';
            app.YoungsModulusEditField = uieditfield(app.MemberInputPanel, 'numeric');
            app.CrosssectionalAreaEditFieldLabel = uilabel(app.MemberInputPanel);
            app.CrosssectionalAreaEditFieldLabel.Text = 'Cross-sectional Area';
            app.CrosssectionalAreaEditField = uieditfield(app.MemberInputPanel, 'numeric');
            app.DensityEditFieldLabel = uilabel(app.MemberInputPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.MemberInputPanel, 'numeric');
            app.MemberTypeLabel = uilabel(app.MemberInputPanel);
            app.MemberTypeLabel.Text = 'Member Type';
            app.MemberTypeDropDown = uidropdown(app.MemberInputPanel);
            app.MemberTypeDropDown.Items = {'Bar', 'Strut', 'Cable'};
            app.MemberTypeDropDown.Value = 'Bar';
            app.GraphScale1mEditFieldLabel = uilabel(app.LeftPanel);
            app.GraphScale1mEditFieldLabel.Text = 'Graph Scale (1:?m)';
            app.GraphScale1mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GraphScale1mEditField.ValueChangedFcn = createCallbackFcn(app, @GraphScale1mEditFieldValueChanged, true);
            app.GraphScale1mEditField.Value = 1;
            app.EditStructurePanel = uipanel(app.LeftPanel);
            app.EditStructurePanel.Title = 'Edit Structure';
            app.RemoveNodeButton = uibutton(app.EditStructurePanel, 'push');
            app.RemoveNodeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNodeButtonPushed, true);
            app.RemoveNodeButton.Text = 'Remove Node';
            app.NodetoEditDropDownLabel = uilabel(app.EditStructurePanel);
            app.NodetoEditDropDownLabel.Text = 'Node to Edit';
            app.NodetoEditDropDown = uidropdown(app.EditStructurePanel);
            app.NodetoEditDropDown.Items = {};
            app.NodetoEditDropDown.Value = {};
            app.MembertoEditDropDownLabel = uilabel(app.EditStructurePanel);
            app.MembertoEditDropDownLabel.Text = 'Member to Edit';
            app.MembertoEditDropDown = uidropdown(app.EditStructurePanel);
            app.MembertoEditDropDown.Items = {};
            app.MembertoEditDropDown.Value = {};
            app.RemoveMemberButton = uibutton(app.EditStructurePanel, 'push');
            app.RemoveMemberButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMemberButtonPushed, true);
            app.RemoveMemberButton.Text = 'Remove Member';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Mode'; 'Frequency (Hz)'};
            app.UITable.RowName = {};
            app.FindFrequenciesButton = uibutton(app.RightPanel, 'push');
            app.FindFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @FindFrequenciesButtonPushed, true);
            app.FindFrequenciesButton.Text = 'Find Frequencies';
            app.SaveFrequenciesButton = uibutton(app.RightPanel, 'push');
            app.SaveFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFrequenciesButtonPushed, true);
            app.SaveFrequenciesButton.Text = 'Save Frequencies';
            app.InputForceButton = uibutton(app.RightPanel, 'push');
            app.InputForceButton.ButtonPushedFcn = createCallbackFcn(app, @InputForceButtonPushed, true);
            app.InputForceButton.Text = 'Input Force';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TensegrityAnalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end